classdef ex2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MutationDropDownLabel         matlab.ui.control.Label
        MutationDropDown              matlab.ui.control.DropDown
        CrossoverDropDownLabel        matlab.ui.control.Label
        CrossoverDropDown             matlab.ui.control.DropDown
        SelectionDropDownLabel        matlab.ui.control.Label
        SelectionDropDown             matlab.ui.control.DropDown
        Image                         matlab.ui.control.Image
        RUNButton                     matlab.ui.control.Button
        FunoFitnessLabel              matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        GeradoresLabel                matlab.ui.control.Label
        EditField_Pg1                 matlab.ui.control.EditField
        EditField_P2g1                matlab.ui.control.EditField
        EditField_Pg2                 matlab.ui.control.EditField
        EditField_P2g2                matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        ResultadosLabel               matlab.ui.control.Label
        ValorfitnessLabel             matlab.ui.control.Label
        Pg1Label                      matlab.ui.control.Label
        Pg2Label                      matlab.ui.control.Label
        PtotalLabel                   matlab.ui.control.Label
        EscolhaButton                 matlab.ui.control.Button
        Label_vf                      matlab.ui.control.Label
        Label_pg1                     matlab.ui.control.Label
        Label_pg2                     matlab.ui.control.Label
        Label_ptotal                  matlab.ui.control.Label
        EditField_P3                  matlab.ui.control.EditField
        ButtonGroup_Plot              matlab.ui.container.ButtonGroup
        rangeCheckBox                 matlab.ui.control.CheckBox
        expectationCheckBox           matlab.ui.control.CheckBox
        bestindivCheckBox             matlab.ui.control.CheckBox
        bestfCheckBox                 matlab.ui.control.CheckBox
        maxconstrCheckBox             matlab.ui.control.CheckBox
        selectionCheckBox             matlab.ui.control.CheckBox
        distanceCheckBox              matlab.ui.control.CheckBox
        scoresCheckBox                matlab.ui.control.CheckBox
        EditField_selection           matlab.ui.control.NumericEditField
        ConstraintsPanel              matlab.ui.container.Panel
        PenaltyFactorEditField        matlab.ui.control.NumericEditField
        PenaltyFactorEditFieldLabel   matlab.ui.control.Label
        InitialPenaltyEditField       matlab.ui.control.NumericEditField
        InitialPenaltyLabel           matlab.ui.control.Label
        StoppingCriteriaPanel         matlab.ui.container.Panel
        MaxTimesEditField             matlab.ui.control.NumericEditField
        MaxTimesEditFieldLabel        matlab.ui.control.Label
        MaxGenerationsEditField       matlab.ui.control.NumericEditField
        MaxGenerationsEditFieldLabel  matlab.ui.control.Label
        Mutation_EditField1           matlab.ui.control.NumericEditField
        Mutation_EditField2           matlab.ui.control.NumericEditField
        Mutation_TextField2           matlab.ui.control.Label
        Mutation_TextField1           matlab.ui.control.Label
        genealogyCheckBox             matlab.ui.control.CheckBox
        stoppingCheckBox              matlab.ui.control.CheckBox
        scorediversityCheckBox        matlab.ui.control.CheckBox
        EditField_crossover           matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
        % Get Plots            
            plots = [];
            all_options = get(app.ButtonGroup_Plot,'Children')
            for i = 1:numel(all_options)
                if all_options(i).Value == 1
                    plots = [plots ("@gaplot" + all_options(i).Text)]
                end
            end
            save('cachefile.mat', 'plots','-append'); 
            
            %carrega as variaveis 
            pg1     = str2double(app.EditField_Pg1.Value);
            pg1_2   = str2double(app.EditField_P2g1.Value);
            pg2     = str2double(app.EditField_Pg2.Value);
            pg2_2   = str2double(app.EditField_P2g2.Value);
            p3      = str2double(app.EditField_P3.Value);
            
            %save cachefile pg1 pg1_2 pg2 pg2_2 p3;
            save('cachefile.mat', 'pg1','pg1_2','pg2','pg2_2','p3','-append'); 
            
            %chama a função gacusto
            gacusto();
            load cachefile x1 x2 x3 x4;
            
            % atualiza os resultados
            app.Label_pg1.Text     = x1;
            app.Label_pg2.Text     = x2;
            app.Label_vf.Text      = x3;
            app.Label_ptotal.Text  = x4;
        
        end

        % Button pushed function: EscolhaButton
        function EscolhaButtonPushed(app, event)
            escolha
            close(app.UIFigure)
        end

        % Value changed function: SelectionDropDown
        function SelectionDropDownValueChanged(app, event)
            value = app.SelectionDropDown.Value
            
            if value=="selectiontournament"
                app.EditField_selection.Visible = 1;
            else
                app.EditField_selection.Visible = 0;            
            end
        end

        % Value changed function: CrossoverDropDown
        function CrossoverDropDownValueChanged(app, event)
            value = app.CrossoverDropDown.Value;
            if value=="crossoverheuristic"
                app.EditField_crossover.Visible = 1;
            else
                app.EditField_crossover.Visible = 0;            
            end
        end

        % Value changed function: MutationDropDown
        function MutationDropDownValueChanged(app, event)
            value = app.MutationDropDown.Value;
            if value=="mutationgaussian"
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 1;
                app.Mutation_TextField2.Visible = 1;
                
                app.Mutation_TextField1.Text = "Scale";
                app.Mutation_EditField1.Value = 1;
                app.Mutation_TextField2.Text = "Shrink";
                app.Mutation_EditField2.Value = 1;
                
            elseif value=="mutationuniform"
                    
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
                
                app.Mutation_TextField1.Text = "Rate";
                app.Mutation_EditField1.Value = 0.01;
            else
                app.Mutation_EditField1.Visible = 0;
                app.Mutation_TextField1.Visible = 0;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Mutation_TextField1 = uilabel(app.UIFigure);
            app.Mutation_TextField1.Text = 'Scale';
            app.Mutation_TextField2 = uilabel(app.UIFigure);
            app.Mutation_TextField2.Text = 'Shrink';
            app.Mutation_EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.Mutation_EditField2.Value = 1;
            app.Mutation_EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.Mutation_EditField1.Value = 1;
            app.StoppingCriteriaPanel = uipanel(app.UIFigure);
            app.StoppingCriteriaPanel.Title = 'Stopping Criteria';
            app.MaxGenerationsEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerationsEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.MaxGenerationsEditField.Value = 200;
            app.MaxTimesEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxTimesEditFieldLabel.Text = 'Max Time (s)';
            app.MaxTimesEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.ConstraintsPanel = uipanel(app.UIFigure);
            app.ConstraintsPanel.Title = 'Constraints';
            app.InitialPenaltyLabel = uilabel(app.ConstraintsPanel);
            app.InitialPenaltyLabel.Text = 'Initial Penalty ';
            app.InitialPenaltyEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.InitialPenaltyEditField.Limits = [1 Inf];
            app.InitialPenaltyEditField.Value = 10;
            app.PenaltyFactorEditFieldLabel = uilabel(app.ConstraintsPanel);
            app.PenaltyFactorEditFieldLabel.Text = 'Penalty Factor ';
            app.PenaltyFactorEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.PenaltyFactorEditField.Limits = [1 Inf];
            app.PenaltyFactorEditField.Value = 100;
            app.EditField_crossover = uieditfield(app.UIFigure, 'numeric');
            app.EditField_crossover.Visible = 'off';
            app.EditField_crossover.Value = 1.2;
            app.EditField_selection = uieditfield(app.UIFigure, 'numeric');
            app.EditField_selection.Visible = 'off';
            app.EditField_selection.Value = 4;
            app.ButtonGroup_Plot = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Plot.Title = 'Plot Options';
            app.scorediversityCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scorediversityCheckBox.Text = 'scorediversity';
            app.stoppingCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.stoppingCheckBox.Text = 'stopping';
            app.stoppingCheckBox.Value = true;
            app.genealogyCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.genealogyCheckBox.Text = 'genealogy';
            app.scoresCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scoresCheckBox.Text = 'scores';
            app.distanceCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.distanceCheckBox.Text = 'distance';
            app.selectionCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.selectionCheckBox.Text = 'selection';
            app.maxconstrCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.maxconstrCheckBox.Text = 'maxconstr';
            app.bestfCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestfCheckBox.Text = 'bestf';
            app.bestfCheckBox.Value = true;
            app.bestindivCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestindivCheckBox.Text = 'bestindiv';
            app.bestindivCheckBox.Value = true;
            app.expectationCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.expectationCheckBox.Text = 'expectation';
            app.expectationCheckBox.Value = true;
            app.rangeCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.rangeCheckBox.Text = 'range';
            app.EditField_P3 = uieditfield(app.UIFigure, 'text');
            app.EditField_P3.Value = '0.5';
            app.Label_ptotal = uilabel(app.UIFigure);
            app.Label_ptotal.Text = '';
            app.Label_pg2 = uilabel(app.UIFigure);
            app.Label_pg2.Text = '';
            app.Label_pg1 = uilabel(app.UIFigure);
            app.Label_pg1.Text = '';
            app.Label_vf = uilabel(app.UIFigure);
            app.Label_vf.Text = '';
            app.EscolhaButton = uibutton(app.UIFigure, 'push');
            app.EscolhaButton.ButtonPushedFcn = createCallbackFcn(app, @EscolhaButtonPushed, true);
            app.EscolhaButton.Text = 'Escolha';
            app.PtotalLabel = uilabel(app.UIFigure);
            app.PtotalLabel.Text = 'Ptotal:';
            app.Pg2Label = uilabel(app.UIFigure);
            app.Pg2Label.Text = 'Pg2:';
            app.Pg1Label = uilabel(app.UIFigure);
            app.Pg1Label.Text = 'Pg1:';
            app.ValorfitnessLabel = uilabel(app.UIFigure);
            app.ValorfitnessLabel.Text = 'Valor fitness:';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField_P2g2 = uieditfield(app.UIFigure, 'text');
            app.EditField_P2g2.Value = '0.2';
            app.EditField_Pg2 = uieditfield(app.UIFigure, 'text');
            app.EditField_Pg2.Value = '0.2';
            app.EditField_P2g1 = uieditfield(app.UIFigure, 'text');
            app.EditField_P2g1.Value = '0.01';
            app.EditField_Pg1 = uieditfield(app.UIFigure, 'text');
            app.EditField_Pg1.Value = '0.3';
            app.GeradoresLabel = uilabel(app.UIFigure);
            app.GeradoresLabel.Text = 'Geradores';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'img_func.png';
            app.FunoFitnessLabel = uilabel(app.UIFigure);
            app.FunoFitnessLabel.Text = 'Função Fitness';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'img_1.png';
            app.SelectionDropDown = uidropdown(app.UIFigure);
            app.SelectionDropDown.Items = {'selectionstochunif', 'selectionremainder', 'selectionuniform', 'selectionroulette', 'selectiontournament'};
            app.SelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionDropDownValueChanged, true);
            app.SelectionDropDown.Value = 'selectionstochunif';
            app.SelectionDropDownLabel = uilabel(app.UIFigure);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.CrossoverDropDown = uidropdown(app.UIFigure);
            app.CrossoverDropDown.Items = {'crossoverscattered', 'crossoversinglepoint', 'crossovertwopoint', 'crossoverintermediate', 'crossoverintermediate', 'crossoverheuristic', 'crossoverarithmetic'};
            app.CrossoverDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossoverDropDownValueChanged, true);
            app.CrossoverDropDown.Value = 'crossoverscattered';
            app.CrossoverDropDownLabel = uilabel(app.UIFigure);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.MutationDropDown = uidropdown(app.UIFigure);
            app.MutationDropDown.Items = {'mutationgaussian', 'mutationuniform', 'mutationadaptfeasible', 'mutationpower', 'mutationpositivebasis'};
            app.MutationDropDown.ValueChangedFcn = createCallbackFcn(app, @MutationDropDownValueChanged, true);
            app.MutationDropDown.Value = 'mutationgaussian';
            app.MutationDropDownLabel = uilabel(app.UIFigure);
            app.MutationDropDownLabel.Text = 'Mutation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end